classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LL6PlusQ7Button       matlab.ui.control.Button
        LL6MinusQ7Button      matlab.ui.control.Button
        LL6TextArea2_7        matlab.ui.control.TextArea
        ESTOPButton           matlab.ui.control.Button
        LL6YawTextArea        matlab.ui.control.TextArea
        YawTextAreaLabel_2    matlab.ui.control.Label
        LL6PitchTextArea      matlab.ui.control.TextArea
        PitchTextAreaLabel_2  matlab.ui.control.Label
        LL6RollTextArea       matlab.ui.control.TextArea
        RollTextAreaLabel_2   matlab.ui.control.Label
        LL6ZTextArea          matlab.ui.control.TextArea
        ZTextAreaLabel_2      matlab.ui.control.Label
        LL6YTextArea          matlab.ui.control.TextArea
        YTextAreaLabel_2      matlab.ui.control.Label
        LL6XTextArea          matlab.ui.control.TextArea
        XTextAreaLabel_2      matlab.ui.control.Label
        LL6RESETButton        matlab.ui.control.Button
        LL6ControlLabel       matlab.ui.control.Label
        LL6PlusQ5Button       matlab.ui.control.Button
        LL6PlusQ6Button       matlab.ui.control.Button
        LL6PlusQ4Button       matlab.ui.control.Button
        LL6PlusQ3Button       matlab.ui.control.Button
        LL6PlusQ2Button       matlab.ui.control.Button
        LL6PlusQ1Button       matlab.ui.control.Button
        LL6MinusQ5Button      matlab.ui.control.Button
        LL6MinusQ6Button      matlab.ui.control.Button
        LL6MinusQ4Button      matlab.ui.control.Button
        LL6MinusQ3Button      matlab.ui.control.Button
        LL6MinusQ2Button      matlab.ui.control.Button
        LL6MinusQ1Button      matlab.ui.control.Button
        LL6TextArea2_6        matlab.ui.control.TextArea
        LL6TextArea2_5        matlab.ui.control.TextArea
        LL6TextArea2_4        matlab.ui.control.TextArea
        LL6TextArea2_3        matlab.ui.control.TextArea
        LL6TextArea2_2        matlab.ui.control.TextArea
        LL6TextArea2          matlab.ui.control.TextArea
        UR3RESETButton        matlab.ui.control.Button
        UR3ControlLabel       matlab.ui.control.Label
        UR3PlusQ5Button       matlab.ui.control.Button
        UR3PlusQ6Button       matlab.ui.control.Button
        UR3PlusQ4Button       matlab.ui.control.Button
        UR3PlusQ3Button       matlab.ui.control.Button
        UR3PlusQ2Button       matlab.ui.control.Button
        UR3PlusQ1Button       matlab.ui.control.Button
        UR3MinusQ5Button      matlab.ui.control.Button
        UR3MinusQ6Button      matlab.ui.control.Button
        UR3MinusQ4Button      matlab.ui.control.Button
        UR3MinusQ3Button      matlab.ui.control.Button
        UR3MinusQ2Button      matlab.ui.control.Button
        UR3MinusQ1Button      matlab.ui.control.Button
        UR3TextArea2_6        matlab.ui.control.TextArea
        UR3TextArea2_5        matlab.ui.control.TextArea
        UR3TextArea2_4        matlab.ui.control.TextArea
        UR3TextArea2_3        matlab.ui.control.TextArea
        UR3TextArea2_2        matlab.ui.control.TextArea
        UR3TextArea2          matlab.ui.control.TextArea
        UR3YawTextArea        matlab.ui.control.TextArea
        YawTextAreaLabel      matlab.ui.control.Label
        UR3PitchTextArea      matlab.ui.control.TextArea
        PitchTextAreaLabel    matlab.ui.control.Label
        UR3RollTextArea       matlab.ui.control.TextArea
        RollTextAreaLabel     matlab.ui.control.Label
        UR3ZTextArea          matlab.ui.control.TextArea
        ZTextAreaLabel        matlab.ui.control.Label
        UR3YTextArea          matlab.ui.control.TextArea
        YTextAreaLabel        matlab.ui.control.Label
        UR3XTextArea          matlab.ui.control.TextArea
        XTextAreaLabel        matlab.ui.control.Label
        CocaColaButton        matlab.ui.control.Button
        FantaButton           matlab.ui.control.Button
        SoloButton            matlab.ui.control.Button
        LemonadeButton        matlab.ui.control.Button
        SelectBeverageLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        UR3MinusQ1 = false;
        UR3MinusQ2 = false;
        UR3MinusQ3 = false;
        UR3MinusQ4 = false;
        UR3MinusQ5 = false;
        UR3MinusQ6 = false;

        UR3PlusQ1 = false;
        UR3PlusQ2 = false;
        UR3PlusQ3 = false;
        UR3PlusQ4 = false;
        UR3PlusQ5 = false;
        UR3PlusQ6 = false;

        UR3_P;
        UR3_T;

        LL6MinusQ1 = false;
        LL6MinusQ2 = false;
        LL6MinusQ3 = false;
        LL6MinusQ4 = false;
        LL6MinusQ5 = false;
        LL6MinusQ6 = false;
        LL6MinusQ7 = false;

        LL6PlusQ1 = false;
        LL6PlusQ2 = false;
        LL6PlusQ3 = false;
        LL6PlusQ4 = false;
        LL6PlusQ5 = false;
        LL6PlusQ6 = false;
        LL6PlusQ7 = false;

        LL6_P;
        LL6_T;

    end
    
    methods (Access = public)
        
        function app = UR3ControlGuiUpdate(app,robot)
            app.UR3_P = robot.model.getpos;
            app.UR3_T = robot.model.fkine(app.UR3_P);
            UR3rpy = rad2deg(tr2rpy(app.UR3_T));

            app.UR3XTextArea.Value = num2str(app.UR3_T(1,4));
            app.UR3YTextArea.Value = num2str(app.UR3_T(2,4));
            app.UR3ZTextArea.Value = num2str(app.UR3_T(3,4));
            app.UR3RollTextArea.Value = num2str(UR3rpy(1));
            app.UR3PitchTextArea.Value = num2str(UR3rpy(2));
            app.UR3YawTextArea.Value = num2str(UR3rpy(3));

            app.UR3TextArea2.Value = num2str(rad2deg(app.UR3_P(1)));
            app.UR3TextArea2_2.Value = num2str(rad2deg(app.UR3_P(2)));
            app.UR3TextArea2_4.Value = num2str(rad2deg(app.UR3_P(3)));
            app.UR3TextArea2_5.Value = num2str(rad2deg(app.UR3_P(4)));
            app.UR3TextArea2_3.Value = num2str(rad2deg(app.UR3_P(5)));
            app.UR3TextArea2_6.Value = num2str(rad2deg(app.UR3_P(6)));
        end
         
        function app = LL6ControlGuiUpdate(app,robot)
            app.LL6_P = robot.model.getpos;
            app.LL6_T = robot.model.fkine(app.LL6_P);
            LL6rpy = rad2deg(tr2rpy(app.LL6_T));

            app.LL6XTextArea.Value = num2str(app.LL6_T(1,4));
            app.LL6YTextArea.Value = num2str(app.LL6_T(2,4));
            app.LL6TextArea.Value = num2str(app.LL6_T(3,4));
            app.LL6RollTextArea.Value = num2str(LL6rpy(1));
            app.LL6PitchTextArea.Value = num2str(LL6rpy(2));
            app.LL6YawTextArea.Value = num2str(LL6rpy(3));

            app.LL6TextArea2.Value = num2str(rad2deg(app.LL6_P(1)));
            app.LL6TextArea2_2.Value = num2str(rad2deg(app.LL6_P(2)));
            app.LL6TextArea2_4.Value = num2str(rad2deg(app.LL6_P(3)));
            app.LL6TextArea2_5.Value = num2str(rad2deg(app.LL6_P(4)));
            app.LL6TextArea2_3.Value = num2str(rad2deg(app.LL6_P(5)));
            app.LL6TextArea2_6.Value = num2str(rad2deg(app.LL6_P(6)));
            app.LL6TextArea2_7.Value = num2str(rad2deg(app.LL6_P(7)));         
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LemonadeButton
        function LemonadeButtonPushed(app, event)
            CollectOrderFromCustomer("Lemonade")
        end

        % Button pushed function: SoloButton
        function SoloButtonPushed(app, event)
            CollectOrderFromCustomer("Solo")
        end

        % Button pushed function: FantaButton
        function FantaButtonPushed(app, event)
            CollectOrderFromCustomer("Fanta")
        end

        % Button pushed function: CocaColaButton
        function CocaColaButtonPushed(app, event)
            CollectOrderFromCustomer("CocaCola")
        end

        % Button pushed function: UR3RESETButton
        function UR3RESETButtonPushed(app, event)
            steps=50;
            qPoint1 = [0,0,0,0,0,0]; %staring q points
            jointTrajectory = jtraj(qPoint,qPoint1,steps); %work out the path it takes

            for i = 1:steps
                estop();
                gripper1.gripperbase_.base = r1.model.fkineUTS(jointTrajectory(i,:)) * transl(0,0,-0.05);
                gripper1.leftFinger.base = gripper1.gripperbase_.base.T * transl(0,0.1,0);
                gripper1.rightFinger.base = gripper1.gripperbase_.base.T * transl(0,0.1,0);

                gripper1.gripperbase_.animate(gripper1.gripperbase_.getpos);
                gripper1.leftFinger.animate(gripper1.leftFinger.getpos);
                gripper1.rightFinger.animate(gripper1.rightFinger.getpos);

                %animate the arm
                animate(r1.model,jointTrajectory(i,:));
                UR3ControlGuiUpdate;
                drawnow();
            end
        end

        % Button pushed function: UR3MinusQ1Button
        function UR3MinusQ1ButtonPushed(app, event)
            if app.UR3MinusQ1 == true
                app.UR3MinusQ1 = false;
            else
                app.UR3MinusQ1 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: LL6RESETButton
        function LL6RESETButtonPushed(app, event)
            steps = 50;
            qPoint1 = [0,0,0,0,0,0,0]; %staring q points
            jointTrajectory = jtraj(qPoint,qPoint1,steps); %work out the path it takes

            for i = 1:steps
                estop();
                gripper2.gripperbase_.base = r2.model.fkineUTS(jointTrajectory(i,:)) * trotz(pi/2) * transl(0,0,0.05);
                gripper2.leftFinger.base = gripper2.gripperbase_.base.T * transl(0,0.1,0);
                gripper2.rightFinger.base = gripper2.gripperbase_.base.T * transl(0,0.1,0);

                gripper2.gripperbase_.animate(gripper2.gripperbase_.getpos);
                gripper2.leftFinger.animate(gripper2.leftFinger.getpos);
                gripper2.rightFinger.animate(gripper2.rightFinger.getpos);

                %animate the arm
                animate(r2.model,jointTrajectory(i,:));
                LL6ControlGuiUpdate;
                drawnow();
            end
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            if app.ESTOP == true
                app.ESTOP = false;
            else
                app.ESTOP = true;
            end
        end

        % Button pushed function: UR3MinusQ2Button
        function UR3MinusQ2ButtonPushed(app, event)
            if app.UR3MinusQ2 == true
                app.UR3MinusQ2 = false;
            else
                app.UR3MinusQ2 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: UR3MinusQ3Button
        function UR3MinusQ3ButtonPushed(app, event)
            if app.UR3MinusQ3 == true
                app.UR3MinusQ3 = false;
            else
                app.UR3MinusQ3 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: UR3MinusQ4Button
        function UR3MinusQ4ButtonPushed(app, event)
            if app.UR3MinusQ4 == true
                app.UR3MinusQ4 = false;
            else
                app.UR3MinusQ4 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: UR3MinusQ5Button
        function UR3MinusQ5ButtonPushed(app, event)
            if app.UR3MinusQ5 == true
                app.UR3MinusQ5 = false;
            else
                app.UR3MinusQ5 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: UR3MinusQ6Button
        function UR3MinusQ6ButtonPushed(app, event)
            if app.UR3MinusQ6 == true
                app.UR3MinusQ6 = false;
            else
                app.UR3MinusQ6 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: UR3PlusQ1Button
        function UR3PlusQ1ButtonPushed(app, event)
            if app.UR3PlusQ1 == true
                app.UR3PlusQ1 = false;
            else
                app.UR3PlusQ1 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: UR3PlusQ2Button
        function UR3PlusQ2ButtonPushed(app, event)
            if app.UR3PlusQ2 == true
                app.UR3PlusQ2 = false;
            else
                app.UR3PlusQ2 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: UR3PlusQ3Button
        function UR3PlusQ3ButtonPushed(app, event)
            if app.UR3PlusQ3 == true
                app.UR3PlusQ3 = false;
            else
                app.UR3PlusQ3 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: UR3PlusQ4Button
        function UR3PlusQ4ButtonPushed(app, event)
            if app.UR3PlusQ4 == true
                app.UR3PlusQ4 = false;
            else
                app.UR3PlusQ4 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: UR3PlusQ5Button
        function UR3PlusQ5ButtonPushed(app, event)
            if app.UR3PlusQ5 == true
                app.UR3PlusQ5 = false;
            else
                app.UR3PlusQ5 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: UR3PlusQ6Button
        function UR3PlusQ6ButtonPushed(app, event)
            if app.UR3PlusQ6 == true
                app.UR3PlusQ6 = false;
            else
                app.UR3PlusQ6 = true;
            end
            app.UR3ControlGuiUpdate;
        end

        % Button pushed function: LL6MinusQ1Button
        function LL6MinusQ1ButtonPushed(app, event)
            if app.LL6MinusQ1 == true
                app.LL6MinusQ1 = false;
            else
                app.LL6MinusQ1 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6MinusQ2Button
        function LL6MinusQ2ButtonPushed(app, event)
            if app.LL6MinusQ2 == true
                app.LL6MinusQ2 = false;
            else
                app.LL6MinusQ2 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6MinusQ3Button
        function LL6MinusQ3ButtonPushed(app, event)
            if app.LL6MinusQ3 == true
                app.LL6MinusQ3 = false;
            else
                app.LL6MinusQ3 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6MinusQ4Button
        function LL6MinusQ4ButtonPushed(app, event)
            if app.LL6MinusQ4 == true
                app.LL6MinusQ4 = false;
            else
                app.LL6MinusQ4 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6MinusQ5Button
        function LL6MinusQ5ButtonPushed(app, event)
            if app.LL6MinusQ5 == true
                app.LL6MinusQ5 = false;
            else
                app.LL6MinusQ5 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6MinusQ6Button
        function LL6MinusQ6ButtonPushed(app, event)
            if app.LL6MinusQ6 == true
                app.LL6MinusQ6 = false;
            else
                app.LL6MinusQ6 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6MinusQ7Button
        function LL6MinusQ7ButtonPushed(app, event)
            if app.LL6MinusQ7 == true
                app.LL6MinusQ7 = false;
            else
                app.LL6MinusQ7 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6PlusQ1Button
        function LL6PlusQ1ButtonPushed(app, event)
            if app.LL6PlusQ1 == true
                app.LL6PlusQ1 = false;
            else
                app.LL6PlusQ1 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6PlusQ2Button
        function LL6PlusQ2ButtonPushed(app, event)
            if app.LL6PlusQ2 == true
                app.LL6PlusQ2 = false;
            else
                app.LL6PlusQ2 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6PlusQ3Button
        function LL6PlusQ3ButtonPushed(app, event)
            if app.LL6PlusQ3 == true
                app.LL6PlusQ3 = false;
            else
                app.LL6PlusQ3 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6PlusQ4Button
        function LL6PlusQ4ButtonPushed(app, event)
            if app.LL6PlusQ4 == true
                app.LL6PlusQ4 = false;
            else
                app.LL6PlusQ4 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6PlusQ5Button
        function LL6PlusQ5ButtonPushed(app, event)
            if app.LL6PlusQ5 == true
                app.LL6PlusQ5 = false;
            else
                app.LL6PlusQ5 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6PlusQ6Button
        function LL6PlusQ6ButtonPushed(app, event)
            if app.LL6PlusQ6 == true
                app.LL6PlusQ6 = false;
            else
                app.LL6PlusQ6 = true;
            end
            app.LL6ControlGuiUpdate;
        end

        % Button pushed function: LL6PlusQ7Button
        function LL6PlusQ7ButtonPushed(app, event)
            if app.LL6PlusQ7 == true
                app.LL6PlusQ7 = false;
            else
                app.LL6PlusQ7 = true;
            end
            app.LL6ControlGuiUpdate;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectBeverageLabel = uilabel(app.UIFigure);
            app.SelectBeverageLabel.Text = 'Select Beverage';
            app.LemonadeButton = uibutton(app.UIFigure, 'push');
            app.LemonadeButton.ButtonPushedFcn = createCallbackFcn(app, @LemonadeButtonPushed, true);
            app.LemonadeButton.Text = 'Lemonade';
            app.SoloButton = uibutton(app.UIFigure, 'push');
            app.SoloButton.ButtonPushedFcn = createCallbackFcn(app, @SoloButtonPushed, true);
            app.SoloButton.Text = 'Solo';
            app.FantaButton = uibutton(app.UIFigure, 'push');
            app.FantaButton.ButtonPushedFcn = createCallbackFcn(app, @FantaButtonPushed, true);
            app.FantaButton.Text = 'Fanta';
            app.CocaColaButton = uibutton(app.UIFigure, 'push');
            app.CocaColaButton.ButtonPushedFcn = createCallbackFcn(app, @CocaColaButtonPushed, true);
            app.CocaColaButton.Text = 'Coca Cola';
            app.XTextAreaLabel = uilabel(app.UIFigure);
            app.XTextAreaLabel.Text = 'X:';
            app.UR3XTextArea = uitextarea(app.UIFigure);
            app.YTextAreaLabel = uilabel(app.UIFigure);
            app.YTextAreaLabel.Text = 'Y:';
            app.UR3YTextArea = uitextarea(app.UIFigure);
            app.ZTextAreaLabel = uilabel(app.UIFigure);
            app.ZTextAreaLabel.Text = 'Z:';
            app.UR3ZTextArea = uitextarea(app.UIFigure);
            app.RollTextAreaLabel = uilabel(app.UIFigure);
            app.RollTextAreaLabel.Text = 'Roll:';
            app.UR3RollTextArea = uitextarea(app.UIFigure);
            app.PitchTextAreaLabel = uilabel(app.UIFigure);
            app.PitchTextAreaLabel.Text = 'Pitch:';
            app.UR3PitchTextArea = uitextarea(app.UIFigure);
            app.YawTextAreaLabel = uilabel(app.UIFigure);
            app.YawTextAreaLabel.Text = 'Yaw:';
            app.UR3YawTextArea = uitextarea(app.UIFigure);
            app.UR3TextArea2 = uitextarea(app.UIFigure);
            app.UR3TextArea2_2 = uitextarea(app.UIFigure);
            app.UR3TextArea2_3 = uitextarea(app.UIFigure);
            app.UR3TextArea2_4 = uitextarea(app.UIFigure);
            app.UR3TextArea2_5 = uitextarea(app.UIFigure);
            app.UR3TextArea2_6 = uitextarea(app.UIFigure);
            app.UR3MinusQ1Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ1Button.ButtonPushedFcn = createCallbackFcn(app, @UR3MinusQ1ButtonPushed, true);
            app.UR3MinusQ1Button.Text = '-q1';
            app.UR3MinusQ2Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ2Button.ButtonPushedFcn = createCallbackFcn(app, @UR3MinusQ2ButtonPushed, true);
            app.UR3MinusQ2Button.Text = '-q2';
            app.UR3MinusQ3Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ3Button.ButtonPushedFcn = createCallbackFcn(app, @UR3MinusQ3ButtonPushed, true);
            app.UR3MinusQ3Button.Text = '-q3';
            app.UR3MinusQ4Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ4Button.ButtonPushedFcn = createCallbackFcn(app, @UR3MinusQ4ButtonPushed, true);
            app.UR3MinusQ4Button.Text = '-q4';
            app.UR3MinusQ6Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ6Button.ButtonPushedFcn = createCallbackFcn(app, @UR3MinusQ6ButtonPushed, true);
            app.UR3MinusQ6Button.Text = '-q6';
            app.UR3MinusQ5Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ5Button.ButtonPushedFcn = createCallbackFcn(app, @UR3MinusQ5ButtonPushed, true);
            app.UR3MinusQ5Button.Text = '-q5';
            app.UR3PlusQ1Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ1Button.ButtonPushedFcn = createCallbackFcn(app, @UR3PlusQ1ButtonPushed, true);
            app.UR3PlusQ1Button.Text = '+q1';
            app.UR3PlusQ2Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ2Button.ButtonPushedFcn = createCallbackFcn(app, @UR3PlusQ2ButtonPushed, true);
            app.UR3PlusQ2Button.Text = '+q2';
            app.UR3PlusQ3Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ3Button.ButtonPushedFcn = createCallbackFcn(app, @UR3PlusQ3ButtonPushed, true);
            app.UR3PlusQ3Button.Text = '+q3';
            app.UR3PlusQ4Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ4Button.ButtonPushedFcn = createCallbackFcn(app, @UR3PlusQ4ButtonPushed, true);
            app.UR3PlusQ4Button.Text = '+q4';
            app.UR3PlusQ6Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ6Button.ButtonPushedFcn = createCallbackFcn(app, @UR3PlusQ6ButtonPushed, true);
            app.UR3PlusQ6Button.Text = '+q6';
            app.UR3PlusQ5Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ5Button.ButtonPushedFcn = createCallbackFcn(app, @UR3PlusQ5ButtonPushed, true);
            app.UR3PlusQ5Button.Text = '+q5';
            app.UR3ControlLabel = uilabel(app.UIFigure);
            app.UR3ControlLabel.Text = 'UR3 Control';
            app.UR3RESETButton = uibutton(app.UIFigure, 'push');
            app.UR3RESETButton.ButtonPushedFcn = createCallbackFcn(app, @UR3RESETButtonPushed, true);
            app.UR3RESETButton.Text = 'RESET';
            app.LL6TextArea2 = uitextarea(app.UIFigure);
            app.LL6TextArea2_2 = uitextarea(app.UIFigure);
            app.LL6TextArea2_3 = uitextarea(app.UIFigure);
            app.LL6TextArea2_4 = uitextarea(app.UIFigure);
            app.LL6TextArea2_5 = uitextarea(app.UIFigure);
            app.LL6TextArea2_6 = uitextarea(app.UIFigure);
            app.LL6MinusQ1Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ1Button.ButtonPushedFcn = createCallbackFcn(app, @LL6MinusQ1ButtonPushed, true);
            app.LL6MinusQ1Button.Text = '-q1';
            app.LL6MinusQ2Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ2Button.ButtonPushedFcn = createCallbackFcn(app, @LL6MinusQ2ButtonPushed, true);
            app.LL6MinusQ2Button.Text = '-q2';
            app.LL6MinusQ3Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ3Button.ButtonPushedFcn = createCallbackFcn(app, @LL6MinusQ3ButtonPushed, true);
            app.LL6MinusQ3Button.Text = '-q3';
            app.LL6MinusQ4Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ4Button.ButtonPushedFcn = createCallbackFcn(app, @LL6MinusQ4ButtonPushed, true);
            app.LL6MinusQ4Button.Text = '-q4';
            app.LL6MinusQ6Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ6Button.ButtonPushedFcn = createCallbackFcn(app, @LL6MinusQ6ButtonPushed, true);
            app.LL6MinusQ6Button.Text = '-q6';
            app.LL6MinusQ5Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ5Button.ButtonPushedFcn = createCallbackFcn(app, @LL6MinusQ5ButtonPushed, true);
            app.LL6MinusQ5Button.Text = '-q5';
            app.LL6PlusQ1Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ1Button.ButtonPushedFcn = createCallbackFcn(app, @LL6PlusQ1ButtonPushed, true);
            app.LL6PlusQ1Button.Text = '+q1';
            app.LL6PlusQ2Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ2Button.ButtonPushedFcn = createCallbackFcn(app, @LL6PlusQ2ButtonPushed, true);
            app.LL6PlusQ2Button.Text = '+q2';
            app.LL6PlusQ3Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ3Button.ButtonPushedFcn = createCallbackFcn(app, @LL6PlusQ3ButtonPushed, true);
            app.LL6PlusQ3Button.Text = '+q3';
            app.LL6PlusQ4Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ4Button.ButtonPushedFcn = createCallbackFcn(app, @LL6PlusQ4ButtonPushed, true);
            app.LL6PlusQ4Button.Text = '+q4';
            app.LL6PlusQ6Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ6Button.ButtonPushedFcn = createCallbackFcn(app, @LL6PlusQ6ButtonPushed, true);
            app.LL6PlusQ6Button.Text = '+q6';
            app.LL6PlusQ5Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ5Button.ButtonPushedFcn = createCallbackFcn(app, @LL6PlusQ5ButtonPushed, true);
            app.LL6PlusQ5Button.Text = '+q5';
            app.LL6ControlLabel = uilabel(app.UIFigure);
            app.LL6ControlLabel.Text = 'LL6 Control';
            app.LL6RESETButton = uibutton(app.UIFigure, 'push');
            app.LL6RESETButton.ButtonPushedFcn = createCallbackFcn(app, @LL6RESETButtonPushed, true);
            app.LL6RESETButton.Text = 'RESET';
            app.XTextAreaLabel_2 = uilabel(app.UIFigure);
            app.XTextAreaLabel_2.Text = 'X:';
            app.LL6XTextArea = uitextarea(app.UIFigure);
            app.YTextAreaLabel_2 = uilabel(app.UIFigure);
            app.YTextAreaLabel_2.Text = 'Y:';
            app.LL6YTextArea = uitextarea(app.UIFigure);
            app.ZTextAreaLabel_2 = uilabel(app.UIFigure);
            app.ZTextAreaLabel_2.Text = 'Z:';
            app.LL6ZTextArea = uitextarea(app.UIFigure);
            app.RollTextAreaLabel_2 = uilabel(app.UIFigure);
            app.RollTextAreaLabel_2.Text = 'Roll:';
            app.LL6RollTextArea = uitextarea(app.UIFigure);
            app.PitchTextAreaLabel_2 = uilabel(app.UIFigure);
            app.PitchTextAreaLabel_2.Text = 'Pitch:';
            app.LL6PitchTextArea = uitextarea(app.UIFigure);
            app.YawTextAreaLabel_2 = uilabel(app.UIFigure);
            app.YawTextAreaLabel_2.Text = 'Yaw:';
            app.LL6YawTextArea = uitextarea(app.UIFigure);
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.LL6TextArea2_7 = uitextarea(app.UIFigure);
            app.LL6MinusQ7Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ7Button.ButtonPushedFcn = createCallbackFcn(app, @LL6MinusQ7ButtonPushed, true);
            app.LL6MinusQ7Button.Text = '-q7';
            app.LL6PlusQ7Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ7Button.ButtonPushedFcn = createCallbackFcn(app, @LL6PlusQ7ButtonPushed, true);
            app.LL6PlusQ7Button.Text = '+q7';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end