classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ESTOPButton           matlab.ui.control.Button
        LL6YawTextArea        matlab.ui.control.TextArea
        YawTextAreaLabel_2    matlab.ui.control.Label
        LL6PitchTextArea      matlab.ui.control.TextArea
        PitchTextAreaLabel_2  matlab.ui.control.Label
        LL6RollTextArea       matlab.ui.control.TextArea
        RollTextAreaLabel_2   matlab.ui.control.Label
        LL6ZTextArea          matlab.ui.control.TextArea
        ZTextAreaLabel_2      matlab.ui.control.Label
        LL6YTextArea          matlab.ui.control.TextArea
        YTextAreaLabel_2      matlab.ui.control.Label
        LL6XTextArea          matlab.ui.control.TextArea
        XTextAreaLabel_2      matlab.ui.control.Label
        LL6RESETButton        matlab.ui.control.Button
        LL6ControlLabel       matlab.ui.control.Label
        LL6PlusQ5Button       matlab.ui.control.Button
        LL6PlusQ6Button       matlab.ui.control.Button
        LL6PlusQ4Button       matlab.ui.control.Button
        LL6PlusQ3Button       matlab.ui.control.Button
        LL6PlusQ2Button       matlab.ui.control.Button
        LL6PlusQ1Button       matlab.ui.control.Button
        LL6MinusQ5Button      matlab.ui.control.Button
        LL6MinusQ6Button      matlab.ui.control.Button
        LL6MinusQ4Button      matlab.ui.control.Button
        LL6MinusQ3Button      matlab.ui.control.Button
        LL6MinusQ2Button      matlab.ui.control.Button
        LL6MinusQ1Button      matlab.ui.control.Button
        LL6TextArea2_6        matlab.ui.control.TextArea
        LL6TextArea2_5        matlab.ui.control.TextArea
        LL6TextArea2_4        matlab.ui.control.TextArea
        LL6TextArea2_3        matlab.ui.control.TextArea
        LL6TextArea2_2        matlab.ui.control.TextArea
        LL6TextArea2          matlab.ui.control.TextArea
        UR3RESETButton        matlab.ui.control.Button
        UR3ControlLabel       matlab.ui.control.Label
        UR3PlusQ5Button       matlab.ui.control.Button
        UR3PlusQ6Button       matlab.ui.control.Button
        UR3PlusQ4Button       matlab.ui.control.Button
        UR3PlusQ3Button       matlab.ui.control.Button
        UR3PlusQ2Button       matlab.ui.control.Button
        UR3PlusQ1Button       matlab.ui.control.Button
        UR3MinusQ5Button      matlab.ui.control.Button
        UR3MinusQ6Button      matlab.ui.control.Button
        UR3MinusQ4Button      matlab.ui.control.Button
        UR3MinusQ3Button      matlab.ui.control.Button
        UR3MinusQ2Button      matlab.ui.control.Button
        UR3MinusQ1Button      matlab.ui.control.Button
        UR3TextArea2_6        matlab.ui.control.TextArea
        UR3TextArea2_5        matlab.ui.control.TextArea
        UR3TextArea2_4        matlab.ui.control.TextArea
        UR3TextArea2_3        matlab.ui.control.TextArea
        UR3TextArea2_2        matlab.ui.control.TextArea
        UR3TextArea2          matlab.ui.control.TextArea
        UR3YawTextArea        matlab.ui.control.TextArea
        YawTextAreaLabel      matlab.ui.control.Label
        UR3PitchTextArea      matlab.ui.control.TextArea
        PitchTextAreaLabel    matlab.ui.control.Label
        UR3RollTextArea       matlab.ui.control.TextArea
        RollTextAreaLabel     matlab.ui.control.Label
        UR3ZTextArea          matlab.ui.control.TextArea
        ZTextAreaLabel        matlab.ui.control.Label
        UR3YTextArea          matlab.ui.control.TextArea
        YTextAreaLabel        matlab.ui.control.Label
        UR3XTextArea          matlab.ui.control.TextArea
        XTextAreaLabel        matlab.ui.control.Label
        CocaColaButton        matlab.ui.control.Button
        FantaButton           matlab.ui.control.Button
        SoloButton            matlab.ui.control.Button
        LemonadeButton        matlab.ui.control.Button
        SelectBeverageLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LemonadeButton
        function LemonadeButtonPushed(app, event)
            % Drink = "Lemonade";
            % assignin('Assignment2Group','Drink',Drink)
            Assignment2Group.CollectOrderFromCustomer(Num)
        end

        % Button pushed function: SoloButton
        function SoloButtonPushed(app, event)
            Drink = "Solo";
            assignin('Assignment2Group','Drink',Drink)
            CollectOrderFromCustomer(Num)
        end

        % Button pushed function: FantaButton
        function FantaButtonPushed(app, event)
            Drink = "Fanta";
            assignin('Assignment2Group','Drink',Drink)
            CollectOrderFromCustomer(Num)
        end

        % Button pushed function: CocaColaButton
        function CocaColaButtonPushed(app, event)
            Drink = "CocaCola";
            assignin('Assignment2Group','Drink',Drink)
            CollectOrderFromCustomer(Num)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectBeverageLabel = uilabel(app.UIFigure);
            app.SelectBeverageLabel.Text = 'Select Beverage';
            app.LemonadeButton = uibutton(app.UIFigure, 'push');
            app.LemonadeButton.ButtonPushedFcn = createCallbackFcn(app, @LemonadeButtonPushed, true);
            app.LemonadeButton.Text = 'Lemonade';
            app.SoloButton = uibutton(app.UIFigure, 'push');
            app.SoloButton.ButtonPushedFcn = createCallbackFcn(app, @SoloButtonPushed, true);
            app.SoloButton.Text = 'Solo';
            app.FantaButton = uibutton(app.UIFigure, 'push');
            app.FantaButton.ButtonPushedFcn = createCallbackFcn(app, @FantaButtonPushed, true);
            app.FantaButton.Text = 'Fanta';
            app.CocaColaButton = uibutton(app.UIFigure, 'push');
            app.CocaColaButton.ButtonPushedFcn = createCallbackFcn(app, @CocaColaButtonPushed, true);
            app.CocaColaButton.Text = 'Coca Cola';
            app.XTextAreaLabel = uilabel(app.UIFigure);
            app.XTextAreaLabel.Text = 'X:';
            app.UR3XTextArea = uitextarea(app.UIFigure);
            app.YTextAreaLabel = uilabel(app.UIFigure);
            app.YTextAreaLabel.Text = 'Y:';
            app.UR3YTextArea = uitextarea(app.UIFigure);
            app.ZTextAreaLabel = uilabel(app.UIFigure);
            app.ZTextAreaLabel.Text = 'Z:';
            app.UR3ZTextArea = uitextarea(app.UIFigure);
            app.RollTextAreaLabel = uilabel(app.UIFigure);
            app.RollTextAreaLabel.Text = 'Roll:';
            app.UR3RollTextArea = uitextarea(app.UIFigure);
            app.PitchTextAreaLabel = uilabel(app.UIFigure);
            app.PitchTextAreaLabel.Text = 'Pitch:';
            app.UR3PitchTextArea = uitextarea(app.UIFigure);
            app.YawTextAreaLabel = uilabel(app.UIFigure);
            app.YawTextAreaLabel.Text = 'Yaw:';
            app.UR3YawTextArea = uitextarea(app.UIFigure);
            app.UR3TextArea2 = uitextarea(app.UIFigure);
            app.UR3TextArea2_2 = uitextarea(app.UIFigure);
            app.UR3TextArea2_3 = uitextarea(app.UIFigure);
            app.UR3TextArea2_4 = uitextarea(app.UIFigure);
            app.UR3TextArea2_5 = uitextarea(app.UIFigure);
            app.UR3TextArea2_6 = uitextarea(app.UIFigure);
            app.UR3MinusQ1Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ1Button.Text = '-q1';
            app.UR3MinusQ2Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ2Button.Text = '-q2';
            app.UR3MinusQ3Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ3Button.Text = '-q3';
            app.UR3MinusQ4Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ4Button.Text = '-q4';
            app.UR3MinusQ6Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ6Button.Text = '-q6';
            app.UR3MinusQ5Button = uibutton(app.UIFigure, 'push');
            app.UR3MinusQ5Button.Text = '-q5';
            app.UR3PlusQ1Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ1Button.Text = '+q1';
            app.UR3PlusQ2Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ2Button.Text = '+q2';
            app.UR3PlusQ3Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ3Button.Text = '+q3';
            app.UR3PlusQ4Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ4Button.Text = '+q4';
            app.UR3PlusQ6Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ6Button.Text = '+q6';
            app.UR3PlusQ5Button = uibutton(app.UIFigure, 'push');
            app.UR3PlusQ5Button.Text = '+q5';
            app.UR3ControlLabel = uilabel(app.UIFigure);
            app.UR3ControlLabel.Text = 'UR3 Control';
            app.UR3RESETButton = uibutton(app.UIFigure, 'push');
            app.UR3RESETButton.Text = 'RESET';
            app.LL6TextArea2 = uitextarea(app.UIFigure);
            app.LL6TextArea2_2 = uitextarea(app.UIFigure);
            app.LL6TextArea2_3 = uitextarea(app.UIFigure);
            app.LL6TextArea2_4 = uitextarea(app.UIFigure);
            app.LL6TextArea2_5 = uitextarea(app.UIFigure);
            app.LL6TextArea2_6 = uitextarea(app.UIFigure);
            app.LL6MinusQ1Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ1Button.Text = '-q1';
            app.LL6MinusQ2Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ2Button.Text = '-q2';
            app.LL6MinusQ3Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ3Button.Text = '-q3';
            app.LL6MinusQ4Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ4Button.Text = '-q4';
            app.LL6MinusQ6Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ6Button.Text = '-q6';
            app.LL6MinusQ5Button = uibutton(app.UIFigure, 'push');
            app.LL6MinusQ5Button.Text = '-q5';
            app.LL6PlusQ1Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ1Button.Text = '+q1';
            app.LL6PlusQ2Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ2Button.Text = '+q2';
            app.LL6PlusQ3Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ3Button.Text = '+q3';
            app.LL6PlusQ4Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ4Button.Text = '+q4';
            app.LL6PlusQ6Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ6Button.Text = '+q6';
            app.LL6PlusQ5Button = uibutton(app.UIFigure, 'push');
            app.LL6PlusQ5Button.Text = '+q5';
            app.LL6ControlLabel = uilabel(app.UIFigure);
            app.LL6ControlLabel.Text = 'LL6 Control';
            app.LL6RESETButton = uibutton(app.UIFigure, 'push');
            app.LL6RESETButton.Text = 'RESET';
            app.XTextAreaLabel_2 = uilabel(app.UIFigure);
            app.XTextAreaLabel_2.Text = 'X:';
            app.LL6XTextArea = uitextarea(app.UIFigure);
            app.YTextAreaLabel_2 = uilabel(app.UIFigure);
            app.YTextAreaLabel_2.Text = 'Y:';
            app.LL6YTextArea = uitextarea(app.UIFigure);
            app.ZTextAreaLabel_2 = uilabel(app.UIFigure);
            app.ZTextAreaLabel_2.Text = 'Z:';
            app.LL6ZTextArea = uitextarea(app.UIFigure);
            app.RollTextAreaLabel_2 = uilabel(app.UIFigure);
            app.RollTextAreaLabel_2.Text = 'Roll:';
            app.LL6RollTextArea = uitextarea(app.UIFigure);
            app.PitchTextAreaLabel_2 = uilabel(app.UIFigure);
            app.PitchTextAreaLabel_2.Text = 'Pitch:';
            app.LL6PitchTextArea = uitextarea(app.UIFigure);
            app.YawTextAreaLabel_2 = uilabel(app.UIFigure);
            app.YawTextAreaLabel_2.Text = 'Yaw:';
            app.LL6YawTextArea = uitextarea(app.UIFigure);
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.Text = 'E-STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end