classdef LinearLite6GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        q7Button_2               matlab.ui.control.Button
        q7Button                 matlab.ui.control.Button
        TextArea2_7              matlab.ui.control.TextArea
        CloseButton              matlab.ui.control.Button
        LinearLite6ControlLabel  matlab.ui.control.Label
        q5Button_2               matlab.ui.control.Button
        q6Button_2               matlab.ui.control.Button
        q4Button_2               matlab.ui.control.Button
        q3Button_2               matlab.ui.control.Button
        q2Button_2               matlab.ui.control.Button
        q1Button_7               matlab.ui.control.Button
        q5Button                 matlab.ui.control.Button
        q6Button                 matlab.ui.control.Button
        q4Button                 matlab.ui.control.Button
        q3Button                 matlab.ui.control.Button
        q2Button                 matlab.ui.control.Button
        q1Button                 matlab.ui.control.Button
        TextArea2_6              matlab.ui.control.TextArea
        TextArea2_5              matlab.ui.control.TextArea
        TextArea2_4              matlab.ui.control.TextArea
        TextArea2_3              matlab.ui.control.TextArea
        TextArea2_2              matlab.ui.control.TextArea
        TextArea2                matlab.ui.control.TextArea
        YawTextArea              matlab.ui.control.TextArea
        YawTextAreaLabel         matlab.ui.control.Label
        PitchTextArea            matlab.ui.control.TextArea
        PitchLabel               matlab.ui.control.Label
        RollTextArea             matlab.ui.control.TextArea
        RollLabel                matlab.ui.control.Label
        ZTextArea                matlab.ui.control.TextArea
        ZTextAreaLabel           matlab.ui.control.Label
        YTextArea                matlab.ui.control.TextArea
        YTextAreaLabel           matlab.ui.control.Label
        XTextArea                matlab.ui.control.TextArea
        XTextAreaLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        Exit = false
        modelLL6;
        
        q1Minus = false;
        q2Minus = false;
        q3Minus = false;
        q4Minus = false;
        q5Minus = false;
        q6Minus = false;
        q7Minus = false;

        q1Plus = false;
        q2Plus = false;
        q3Plus = false;
        q4Plus = false;
        q5Plus = false;
        q6Plus = false;
        q7Plus = false;

        LL6_P = [0     0     0     0     0     0];
        LL6_T;
        qrLL6 = [0	pi/2  -pi/2	0	0	0];
        r2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ax = gca;

            % Make, save and plot some robots            
            mdl_LinearLite6();
            app.modelLL6 = LinearLite6;
            app.LL6_P = [0     0     0     0     0     0];
            plot(app.modelLL6,app.qrLL6,'workspace',[-2 2 -2 2 -0.5 2])            
            hold on;  

            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            app.UR3_P = [app.q1     app.q2     app.q3     app.q4     app.q5     app.q6];
            app.UR3_T = app.modelUR3.fkineUTS(app.UR3_P);
            rpy = rad2deg(tr2rpy(app.UR3_T));

            app.XTextArea.Value = num2str(app.UR3_T(1,4));
            app.YTextArea.Value = num2str(app.UR3_T(2,4));
            app.ZTextArea.Value = num2str(app.UR3_T(3,4));
            app.RollTextArea.Value = num2str(rpy(1));
            app.PitchTextArea.Value = num2str(rpy(2));
            app.YawTextArea.Value = num2str(rpy(3));

            app.TextArea2.Value = num2str(rad2deg(app.UR3_P(1)));
            app.TextArea2_2.Value = num2str(rad2deg(app.UR3_P(2)));
            app.TextArea2_4.Value = num2str(rad2deg(app.UR3_P(3)));
            app.TextArea2_5.Value = num2str(rad2deg(app.UR3_P(4)));
            app.TextArea2_3.Value = num2str(rad2deg(app.UR3_P(5)));
            app.TextArea2_6.Value = num2str(rad2deg(app.UR3_P(6)));
        end

        % Button pushed function: q5Button_2
        function q5Button_2Pushed(app, event)
            if app.q5Plus == true
                app.q5Plus = false;
            else
                app.q5Plus = true;
            end
        end

        % Button pushed function: q6Button_2
        function q6Button_2Pushed(app, event)
            if app.q6Plus == true
                app.q6Plus = false;
            else
                app.q6Plus = true;
            end
        end

        % Button pushed function: q4Button_2
        function q4Button_2Pushed(app, event)
            if app.q4Plus == true
                app.q4Plus = false;
            else
                app.q4Plus = true;
            end
        end

        % Button pushed function: q3Button_2
        function q3Button_2Pushed(app, event)
            if app.q3Plus == true
                app.q3Plus = false;
            else
                app.q3Plus = true;
            end
        end

        % Button pushed function: q2Button_2
        function q2Button_2Pushed(app, event)
            if app.q2Plus == true
                app.q2Plus = false;
            else
                app.q2Plus = true;
            end
        end

        % Button pushed function: q1Button_7
        function q1Button_7Pushed(app, event)
            if app.q1Plus == true
                app.q1Plus = false;
            else
                app.q1Plus = true;
            end
        end

        % Button pushed function: q5Button
        function q5ButtonPushed(app, event)
            if app.q5Minus == true
                app.q5Minus = false;
            else
                app.q5Minus = true;
            end
        end

        % Button pushed function: q6Button
        function q6ButtonPushed(app, event)
            if app.q6Minus == true
                app.q6Minus = false;
            else
                app.q6Minus = true;
            end
        end

        % Button pushed function: q4Button
        function q4ButtonPushed(app, event)
            if app.q4Minus == true
                app.q4Minus = false;
            else
                app.q4Minus = true;
            end
        end

        % Button pushed function: q3Button
        function q3ButtonPushed(app, event)
            if app.q3Minus == true
                app.q3Minus = false;
            else
                app.q3Minus = true;
            end
        end

        % Button pushed function: q2Button
        function q2ButtonPushed(app, event)
            if app.q2Minus == true
                app.q2Minus = false;
            else
                app.q2Minus = true;
            end
        end

        % Button pushed function: q1Button
        function q1ButtonPushed(app, event)
            if app.q1Minus == true
                app.q1Minus = false;
            else
                app.q1Minus = true;
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if app.Exit == true
                app.Exit = false;
            else
                app.Exit = true;
            end
        end

        % Button pushed function: q7Button
        function q7ButtonPushed(app, event)
            if app.q7Minus == true
                app.q7Minus = false;
            else
                app.q7Minus = true;
            end
        end

        % Button pushed function: q7Button_2
        function q7Button_2Pushed(app, event)
            if app.q7Plus == true
                app.q7Plus = false;
            else
                app.q7Plus = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XTextAreaLabel = uilabel(app.UIFigure);
            app.XTextAreaLabel.Text = 'X:';
            app.XTextArea = uitextarea(app.UIFigure);
            app.YTextAreaLabel = uilabel(app.UIFigure);
            app.YTextAreaLabel.Text = 'Y:';
            app.YTextArea = uitextarea(app.UIFigure);
            app.ZTextAreaLabel = uilabel(app.UIFigure);
            app.ZTextAreaLabel.Text = 'Z:';
            app.ZTextArea = uitextarea(app.UIFigure);
            app.RollLabel = uilabel(app.UIFigure);
            app.RollLabel.Text = 'Roll:';
            app.RollTextArea = uitextarea(app.UIFigure);
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = 'Pitch:';
            app.PitchTextArea = uitextarea(app.UIFigure);
            app.YawTextAreaLabel = uilabel(app.UIFigure);
            app.YawTextAreaLabel.Text = 'Yaw:';
            app.YawTextArea = uitextarea(app.UIFigure);
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2_2 = uitextarea(app.UIFigure);
            app.TextArea2_3 = uitextarea(app.UIFigure);
            app.TextArea2_4 = uitextarea(app.UIFigure);
            app.TextArea2_5 = uitextarea(app.UIFigure);
            app.TextArea2_6 = uitextarea(app.UIFigure);
            app.q1Button = uibutton(app.UIFigure, 'push');
            app.q1Button.ButtonPushedFcn = createCallbackFcn(app, @q1ButtonPushed, true);
            app.q1Button.Text = '-q1';
            app.q2Button = uibutton(app.UIFigure, 'push');
            app.q2Button.ButtonPushedFcn = createCallbackFcn(app, @q2ButtonPushed, true);
            app.q2Button.Text = '-q2';
            app.q3Button = uibutton(app.UIFigure, 'push');
            app.q3Button.ButtonPushedFcn = createCallbackFcn(app, @q3ButtonPushed, true);
            app.q3Button.Text = '-q3';
            app.q4Button = uibutton(app.UIFigure, 'push');
            app.q4Button.ButtonPushedFcn = createCallbackFcn(app, @q4ButtonPushed, true);
            app.q4Button.Text = '-q4';
            app.q6Button = uibutton(app.UIFigure, 'push');
            app.q6Button.ButtonPushedFcn = createCallbackFcn(app, @q6ButtonPushed, true);
            app.q6Button.Text = '-q6';
            app.q5Button = uibutton(app.UIFigure, 'push');
            app.q5Button.ButtonPushedFcn = createCallbackFcn(app, @q5ButtonPushed, true);
            app.q5Button.Text = '-q5';
            app.q1Button_7 = uibutton(app.UIFigure, 'push');
            app.q1Button_7.ButtonPushedFcn = createCallbackFcn(app, @q1Button_7Pushed, true);
            app.q1Button_7.Text = '+q1';
            app.q2Button_2 = uibutton(app.UIFigure, 'push');
            app.q2Button_2.ButtonPushedFcn = createCallbackFcn(app, @q2Button_2Pushed, true);
            app.q2Button_2.Text = '+q2';
            app.q3Button_2 = uibutton(app.UIFigure, 'push');
            app.q3Button_2.ButtonPushedFcn = createCallbackFcn(app, @q3Button_2Pushed, true);
            app.q3Button_2.Text = '+q3';
            app.q4Button_2 = uibutton(app.UIFigure, 'push');
            app.q4Button_2.ButtonPushedFcn = createCallbackFcn(app, @q4Button_2Pushed, true);
            app.q4Button_2.Text = '+q4';
            app.q6Button_2 = uibutton(app.UIFigure, 'push');
            app.q6Button_2.ButtonPushedFcn = createCallbackFcn(app, @q6Button_2Pushed, true);
            app.q6Button_2.Text = '+q6';
            app.q5Button_2 = uibutton(app.UIFigure, 'push');
            app.q5Button_2.ButtonPushedFcn = createCallbackFcn(app, @q5Button_2Pushed, true);
            app.q5Button_2.Text = '+q5';
            app.LinearLite6ControlLabel = uilabel(app.UIFigure);
            app.LinearLite6ControlLabel.Text = 'Linear Lite 6 Control';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea2_7 = uitextarea(app.UIFigure);
            app.q7Button = uibutton(app.UIFigure, 'push');
            app.q7Button.ButtonPushedFcn = createCallbackFcn(app, @q7ButtonPushed, true);
            app.q7Button.Text = '-q7';
            app.q7Button_2 = uibutton(app.UIFigure, 'push');
            app.q7Button_2.ButtonPushedFcn = createCallbackFcn(app, @q7Button_2Pushed, true);
            app.q7Button_2.Text = '+q7';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LinearLite6GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end