classdef eStopGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ControlLinearLite6Button  matlab.ui.control.Button
        ResetLinearLite6Button    matlab.ui.control.Button
        ControlUR3Button          matlab.ui.control.Button
        ResetUR3Button            matlab.ui.control.Button
        ContinueButton            matlab.ui.control.Button
    end


    properties (Access = public)
        Continue = false; % Description
        ResetUR3 = false;
        ControlUR3 = false;
        ResetLinearLite6 = false;
        ControlLinearLite6 = false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            if app.Continue == true
                app.Continue = false;
            else
                app.Continue = true;
            end
        end

        % Button pushed function: ResetLinearLite6Button
        function ResetLinearLite6ButtonPushed(app, event)
            if app.ResetLinearLite6 == true
                app.ResetLinearLite6 = false;
            else
                app.ResetLinearLite6 = true;
            end
        end

        % Button pushed function: ControlUR3Button
        function ControlUR3ButtonPushed(app, event)
            if app.ControlUR3 == true
                app.ControlUR3 = false;
            else
                app.ControlUR3 = true;
            end
        end

        % Button pushed function: ResetUR3Button
        function ResetUR3ButtonPushed(app, event)
            if app.ResetUR3 == true
                app.ResetUR3 = false;
            else
                app.ResetUR3 = true;
            end
        end

        % Button pushed function: ControlLinearLite6Button
        function ControlLinearLite6ButtonPushed(app, event)
            if app.ControlLinearLite6 == true
                app.ControlLinearLite6 = false;
            else
                app.ControlLinearLite6 = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ResetUR3Button = uibutton(app.UIFigure, 'push');
            app.ResetUR3Button.ButtonPushedFcn = createCallbackFcn(app, @ResetUR3ButtonPushed, true);
            app.ResetUR3Button.Text = 'Reset UR3';
            app.ControlUR3Button = uibutton(app.UIFigure, 'push');
            app.ControlUR3Button.ButtonPushedFcn = createCallbackFcn(app, @ControlUR3ButtonPushed, true);
            app.ControlUR3Button.Text = 'Control UR3';
            app.ResetLinearLite6Button = uibutton(app.UIFigure, 'push');
            app.ResetLinearLite6Button.ButtonPushedFcn = createCallbackFcn(app, @ResetLinearLite6ButtonPushed, true);
            app.ResetLinearLite6Button.Text = 'Reset Linear Lite 6';
            app.ControlLinearLite6Button = uibutton(app.UIFigure, 'push');
            app.ControlLinearLite6Button.ButtonPushedFcn = createCallbackFcn(app, @ControlLinearLite6ButtonPushed, true);
            app.ControlLinearLite6Button.Text = 'Control Linear Lite 6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eStopGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end