classdef mainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ESTOPButton               matlab.ui.control.Button
        ControlUR3Button          matlab.ui.control.Button
        ControlLinearLite6Button  matlab.ui.control.Button
        OrderRefreshmentButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        ESTOP = false; % Description
        START = false;
        UR3Control = false;
        Lite6Control = false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
             if app.ESTOP == true
                app.ESTOP = false;
            else
                app.ESTOP = true;
            end
        end

        % Button pushed function: OrderRefreshmentButton
        function OrderRefreshmentButtonPushed(app, event)
            if app.OrderRefreshment == true
                app.OrderRefreshment = false;
            else
                app.OrderRefreshment = true;
            end
        end

        % Button pushed function: ControlLinearLite6Button
        function ControlLinearLite6ButtonPushed(app, event)
            if app.Lite6Control == true
                app.Lite6Control = false;
            else
                app.Lite6Control = true;
            end
        end

        % Button pushed function: ControlUR3Button
        function ControlUR3ButtonPushed(app, event)
            if app.UR3Control == true
                app.UR3Control = false;
            else
                app.UR3Control = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OrderRefreshmentButton = uibutton(app.UIFigure, 'push');
            app.OrderRefreshmentButton.ButtonPushedFcn = createCallbackFcn(app, @OrderRefreshmentButtonPushed, true);
            app.OrderRefreshmentButton.Text = 'Order Refreshment';
            app.ControlLinearLite6Button = uibutton(app.UIFigure, 'push');
            app.ControlLinearLite6Button.ButtonPushedFcn = createCallbackFcn(app, @ControlLinearLite6ButtonPushed, true);
            app.ControlLinearLite6Button.Text = 'Control Linear Lite 6';
            app.ControlUR3Button = uibutton(app.UIFigure, 'push');
            app.ControlUR3Button.ButtonPushedFcn = createCallbackFcn(app, @ControlUR3ButtonPushed, true);
            app.ControlUR3Button.Text = 'Control UR3';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E - STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end